classdef Chirag_Patel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menue                       matlab.ui.container.Menu
        AnalyzerMenu                matlab.ui.container.Menu
        Toolbar                     matlab.ui.container.Toolbar
        PushTool2                   matlab.ui.container.toolbar.PushTool
        Button                      matlab.ui.control.Button
        SpanEditField_5             matlab.ui.control.EditField
        SpanEditField_2Label        matlab.ui.control.Label
        CenterFreqEditField_3       matlab.ui.control.EditField
        CenterFreqEditField_2Label  matlab.ui.control.Label
        powerEditField              matlab.ui.control.EditField
        powerEditFieldLabel         matlab.ui.control.Label
        BWEditField_4               matlab.ui.control.EditField
        BWEditField_2Label          matlab.ui.control.Label
        FrequencyEditField_2        matlab.ui.control.EditField
        FrequencyEditField_2Label   matlab.ui.control.Label
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        AnalyzerButton              matlab.ui.control.StateButton
        GeneratorButton             matlab.ui.control.StateButton
        Lamp                        matlab.ui.control.Lamp
        TurnofftheMarkerButton      matlab.ui.control.Button
        SetamarkeronPeakButton      matlab.ui.control.Button
        GenRFopONOFFButton          matlab.ui.control.StateButton
        ArcusMotorButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        obj1;
        obj;
        interface = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GeneratorButton
        function GeneratorButtonValueChanged(app, event)
            value = app.GeneratorButton.Value;
            if (value==1)
                app.obj1 = Generator_MXG_N5182A();
                app.Lamp_2.Color='green';
            else
                app.obj1.Disconnect_Generator();
                app.Lamp_2.Color='red';
            end
        end

        % Value changed function: AnalyzerButton
        function AnalyzerButtonValueChanged(app, event)
            value = app.AnalyzerButton.Value;
            if (value==1)
                app.obj = Analyzer_EXA_N9010A();
                app.Lamp_3.Color='green';
            else
                app.obj.Disconnect_Analyzer();
                app.Lamp_3.Color='red';
            end
        end

        % Value changed function: GenRFopONOFFButton
        function GenRFopONOFFButtonValueChanged(app, event)
            value = app.GenRFopONOFFButton.Value;
            if (value==1)
                app.obj1.RF_OutPut_ON();
                app.Lamp.Color='green';
            else
                app.obj1.RF_OutPut_OFF();
                app.Lamp.Color='red';
            end
        end

        % Value changed function: FrequencyEditField_2
        function FrequencyEditField_2ValueChanged(app, event)
            value = app.FrequencyEditField_2.Value;
            app.obj1.SetFrequeny(num2str(value));
        end

        % Value changed function: powerEditField
        function powerEditFieldValueChanged(app, event)
            value = app.powerEditField.Value;
            app.obj1.SetPowerLevel(num2str(value));
        end

        % Value changed function: CenterFreqEditField_3
        function CenterFreqEditField_3ValueChanged(app, event)
            value = app.CenterFreqEditField_3.Value;
            app.obj.Set_Center_Frequency(value);
        end

        % Value changed function: BWEditField_4
        function BWEditField_4ValueChanged(app, event)
            value = app.BWEditField_4.Value;
            app.obj.Set_BnadWidth(value);
        end

        % Value changed function: SpanEditField_5
        function SpanEditField_5ValueChanged(app, event)
            value = app.SpanEditField_5.Value;
            app.obj.Set_Span(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menue = uimenu(app.UIFigure);
            app.Menue.Text = 'Menue';
            app.AnalyzerMenu = uimenu(app.Menue);
            app.AnalyzerMenu.Text = 'Analyzer';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.ArcusMotorButton = uibutton(app.UIFigure, 'push');
            app.ArcusMotorButton.Tag = 'Push Button';
            app.ArcusMotorButton.Text = 'Arcus Motor';
            app.GenRFopONOFFButton = uibutton(app.UIFigure, 'state');
            app.GenRFopONOFFButton.ValueChangedFcn = createCallbackFcn(app, @GenRFopONOFFButtonValueChanged, true);
            app.GenRFopONOFFButton.Tag = 'state button on and off';
            app.GenRFopONOFFButton.Text = 'Gen. RF o/p ON/OFF';
            app.SetamarkeronPeakButton = uibutton(app.UIFigure, 'push');
            app.SetamarkeronPeakButton.Text = 'Set a marker on Peak';
            app.TurnofftheMarkerButton = uibutton(app.UIFigure, 'push');
            app.TurnofftheMarkerButton.Text = 'Turn off the Marker';
            app.Lamp = uilamp(app.UIFigure);
            app.GeneratorButton = uibutton(app.UIFigure, 'state');
            app.GeneratorButton.ValueChangedFcn = createCallbackFcn(app, @GeneratorButtonValueChanged, true);
            app.GeneratorButton.Text = 'Generator ';
            app.AnalyzerButton = uibutton(app.UIFigure, 'state');
            app.AnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzerButtonValueChanged, true);
            app.AnalyzerButton.Text = 'Analyzer ';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.FrequencyEditField_2Label = uilabel(app.UIFigure);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditField_2ValueChanged, true);
            app.FrequencyEditField_2.Placeholder = '10.0e8';
            app.BWEditField_2Label = uilabel(app.UIFigure);
            app.BWEditField_2Label.Text = 'BW';
            app.BWEditField_4 = uieditfield(app.UIFigure, 'text');
            app.BWEditField_4.ValueChangedFcn = createCallbackFcn(app, @BWEditField_4ValueChanged, true);
            app.BWEditField_4.Placeholder = '3.0e6';
            app.powerEditFieldLabel = uilabel(app.UIFigure);
            app.powerEditFieldLabel.Text = 'power';
            app.powerEditField = uieditfield(app.UIFigure, 'text');
            app.powerEditField.ValueChangedFcn = createCallbackFcn(app, @powerEditFieldValueChanged, true);
            app.powerEditField.Placeholder = '10';
            app.CenterFreqEditField_2Label = uilabel(app.UIFigure);
            app.CenterFreqEditField_2Label.Text = 'Center Freq.';
            app.CenterFreqEditField_3 = uieditfield(app.UIFigure, 'text');
            app.CenterFreqEditField_3.ValueChangedFcn = createCallbackFcn(app, @CenterFreqEditField_3ValueChanged, true);
            app.CenterFreqEditField_3.Placeholder = '3.5e9';
            app.SpanEditField_2Label = uilabel(app.UIFigure);
            app.SpanEditField_2Label.Text = 'Span';
            app.SpanEditField_5 = uieditfield(app.UIFigure, 'text');
            app.SpanEditField_5.ValueChangedFcn = createCallbackFcn(app, @SpanEditField_5ValueChanged, true);
            app.SpanEditField_5.Placeholder = '7.0e9';
            app.Button = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chirag_Patel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end